classdef app_layout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterDesignUIFigure            matlab.ui.Figure
        SaveMenu                        matlab.ui.container.Menu
        CajonMenu                       matlab.ui.container.Menu
        TaishogotoMenu                  matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        InputMenu                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        AudioTab                        matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        GridLayout2_3                   matlab.ui.container.GridLayout
        PlayKotoButton                  matlab.ui.control.Button
        GridLayout2_2                   matlab.ui.container.GridLayout
        PlayCajonButton                 matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        RecordButton                    matlab.ui.control.Button
        PlayRecordedButton              matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        RecordingLabel                  matlab.ui.control.Label
        kotoAudioFT                     matlab.ui.control.UIAxes
        kotoAxes                        matlab.ui.control.UIAxes
        cajonAxes                       matlab.ui.control.UIAxes
        ogAudioAxes                     matlab.ui.control.UIAxes
        cajonAudioFT                    matlab.ui.control.UIAxes
        ogAudioFT                       matlab.ui.control.UIAxes
        FrequencyResponseTab            matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        aCoeffKoto                      matlab.ui.control.Label
        aCoeffCajon                     matlab.ui.control.Label
        bCoeffKoto                      matlab.ui.control.Label
        bCoeffCajon                     matlab.ui.control.Label
        TaishogotoFilter800Hz2kHzLabel  matlab.ui.control.Label
        CajonFilter50Hz500HzLabel       matlab.ui.control.Label
        FilterCoefficients8thOrderLabel  matlab.ui.control.Label
        freqResponseGraph               matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        YaxisMenu                       matlab.ui.container.Menu
        dBMenu                          matlab.ui.container.Menu
        absMenu                         matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        NormalizeMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        ogAudioVec; % Original Audio
        cajonAudioVec; % Cajon Audio
        kotoAudioVec; % Koto Audio
        Fs = 44100; %Sampling Frequency
        N = 4; % Filter Order
        recObj;
        showMax = "off";

        fl1 = 50;
        fh1 = 500;

        fl2 = 800;
        fh2 = 2000;

        dialogapp;
        f = 0;

        normalize = "off";
    end
    
    methods (Access = public)
        
        function filtered = filterCajon(app)
            [b, a] = butter(app.N, [app.fl1 app.fh1]/(app.Fs/2), "bandpass");
            filtered = filter(b, a, app.ogAudioVec);
        end
        
        function filtered = filterKoto(app)
            [b, a] = butter(app.N, [app.fl2 app.fh2]/(app.Fs/2), "bandpass");
            filtered = filter(b, a, app.ogAudioVec);
        end

        function plotFreqResponse(app)
            [bc, ac] = butter(app.N, [app.fl1 app.fh1]/(app.Fs/2), "bandpass");
            bc_rounded = round(bc/(10^(-5)), 4);
            app.bCoeffCajon.Text = append(replace(mat2str(bc_rounded), " ", "    "), " x10^-5");
            ac_rounded = round(ac, 4);
            app.aCoeffCajon.Text = replace(mat2str(ac_rounded), " ", "    ");

            [H, F] = freqz(bc, ac, 1024, app.Fs);
            plot1 = plot(app.freqResponseGraph, F, abs(H));

            [bk, ak] = butter(app.N, [app.fl2 app.fh2]/(app.Fs/2), "bandpass");
            bk_rounded = round(bk/(10^(-5)), 4);
            app.bCoeffKoto.Text = append(replace(mat2str(bk_rounded), " ", "    "), " x10^-5");
            ak_rounded = round(ak, 4);
            app.aCoeffKoto.Text = replace(mat2str(ak_rounded), " ", "    ");

            [H, F] = freqz(bk, ak, 1024, app.Fs);
            hold(app.freqResponseGraph, "on");
            plot2 = plot(app.freqResponseGraph, F, abs(H));

            f_cursor = app.fl1;
            line(app.freqResponseGraph, [f_cursor f_cursor], ylim(app.freqResponseGraph), 'Color', 'b', 'LineStyle', '--', 'LineWidth', 1);
            text(app.freqResponseGraph, f_cursor, 0, sprintf(' %.1f Hz', f_cursor), 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'Color', 'k');

            f_cursor = app.fh1;
            line(app.freqResponseGraph, [f_cursor f_cursor], ylim(app.freqResponseGraph), 'Color', 'b', 'LineStyle', '--', 'LineWidth', 1);
            text(app.freqResponseGraph, f_cursor, max(abs(H)), sprintf(' %.1f Hz', f_cursor), 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'Color', 'k');

            f_cursor = app.fl2;
            line(app.freqResponseGraph, [f_cursor f_cursor], ylim(app.freqResponseGraph), 'Color', 'r', 'LineStyle', '--', 'LineWidth', 1);
            text(app.freqResponseGraph, f_cursor, 0, sprintf(' %.1f Hz', f_cursor), 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'Color', 'k');

            f_cursor = app.fh2;
            line(app.freqResponseGraph, [f_cursor f_cursor], ylim(app.freqResponseGraph), 'Color', 'r', 'LineStyle', '--', 'LineWidth', 1);
            text(app.freqResponseGraph, f_cursor, max(abs(H)), sprintf(' %.1f Hz', f_cursor), 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'Color', 'k');

            xlim(app.freqResponseGraph, [0 app.Fs/10]);

            legend(app.freqResponseGraph, [plot1 plot2], "Cajon Filter", "Taishogoto Filter");

            hold(app.freqResponseGraph, "off");
        end
        
        function update(app)
            L = length(app.ogAudioVec);
            fn = app.Fs*(0:(L/2))/L;
            f_shifted = (-L/2:L/2-1)*(app.Fs/L);
            % Step 5: Extract the positive half of the FFT
            positive_indices = f_shifted >= 0;     % Find the indices for positive frequencies
            f_positive = f_shifted(positive_indices);  % Positive frequencies only


            plot(app.ogAudioAxes, app.ogAudioVec, 'b-');
            ogFT = fftshift(fft(app.ogAudioVec));
            ogFT_positive = ogFT(positive_indices);
            app.ogAudioFT.YLim = [0 app.Fs];
            if app.normalize == "on"
                ogFT_positive = ogFT_positive/app.Fs;
                app.ogAudioFT.YLim = [0 1];
            end
            plot(app.ogAudioFT, f_positive, abs(ogFT_positive), 'b-');
            yLims = app.ogAudioAxes.YLim;
            % yFTMaxLim = 1;
            % app.ogAudioFT.YLim = [0 yFTMaxLim];
            app.ogAudioFT.XLim = [0 3000];
            z0 = zoom(app.ogAudioFT);
            z0.motion = "horizontal";

            app.cajonAudioVec = app.filterCajon();
            plot(app.cajonAxes, app.cajonAudioVec, 'g-');
            app.cajonAxes.YLim = [yLims(1) yLims(2)];
            cajonFT = fftshift(fft(app.cajonAudioVec));
            cajonFT_positive = cajonFT(positive_indices);
            app.cajonAudioFT.YLim = [0 app.Fs];
            if app.normalize == "on"
                cajonFT_positive = cajonFT_positive/app.Fs;
                app.cajonAudioFT.YLim = [0 1];
            end
            plot(app.cajonAudioFT, f_positive, abs(cajonFT_positive), 'g-');
            % app.cajonAudioFT.YLim = [0 yFTMaxLim];
            app.cajonAudioFT.XLim = [0 3000];
            z1 = zoom(app.cajonAudioFT);
            z1.motion = "horizontal";
            if app.showMax == "on"
                [ymax, idx_max] = max(abs(cajonFT_positive));
                text(app.cajonAudioFT, fn(idx_max), ymax+0.05, num2str(ymax), "HorizontalAlignment", "left", "Color", "k");
            end

            app.kotoAudioVec = app.filterKoto();
            plot(app.kotoAxes, app.kotoAudioVec, "-");
            app.kotoAxes.YLim = [yLims(1) yLims(2)];
            kotoFT = fftshift(fft(app.kotoAudioVec));
            kotoFT_positive = kotoFT(positive_indices);
            app.kotoAudioFT.YLim = [0 app.Fs];
            if app.normalize == "on"
                kotoFT_positive = kotoFT_positive/app.Fs;
                app.kotoAudioFT.YLim = [0 1];
            end
            plot(app.kotoAudioFT, f_positive, abs(kotoFT_positive), 'g-');
            % app.kotoAudioFT.YLim = [0 22050];
            app.kotoAudioFT.XLim = [0 3000];

            h1 = app.kotoAxes.Children;
            line1 = h1(1);
            line1.Color = "#ff9900";

            h2 = app.kotoAudioFT.Children;
            line2 = h2(1);
            line2.Color = "#ff9900";
            z2 = zoom(app.kotoAudioFT);
            z2.motion = "horizontal";
            
            if app.showMax == "on"
                [ymax, idx_max] = max(abs(kotoFT_positive));
                text(app.kotoAudioFT, fn(idx_max), ymax+0.05, num2str(ymax), "HorizontalAlignment", "left", "Color", "k");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.plotFreqResponse();
            app.NormalizeMenu.Checked = "off";
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if app.RecordButton.Text == "REC"
                app.RecordButton.Text = "STOP";
                app.PlayRecordedButton.Enable = "off";
                app.PlayCajonButton.Enable = "off";
                app.PlayKotoButton.Enable = "off";

                clear sound;

                app.Fs = 48000;
                audioDevice = 1;
                app.recObj = audiorecorder(app.Fs, 16, 1, audioDevice);
                record(app.recObj);
                app.RecordingLabel.Visible = "on";
            elseif app.RecordButton.Text == "STOP"
                app.RecordButton.Text = "REC";
                app.PlayRecordedButton.Enable = "on";
                app.PlayCajonButton.Enable = "on";
                app.PlayKotoButton.Enable = "on";

                stop(app.recObj);
                app.ogAudioVec = getaudiodata(app.recObj);
                app.update();
                app.RecordingLabel.Visible = "off";
            end
            app.showMax = "off";
        end

        % Button pushed function: PlayRecordedButton
        function PlayRecordedButtonPushed(app, event)
            if app.PlayRecordedButton.Text == "PLAY"
                app.PlayRecordedButton.Text = "STOP";
                app.PlayCajonButton.Text = "PLAY";
                app.PlayKotoButton.Text = "PLAY";

                clear sound;
                sound(app.ogAudioVec, app.Fs);
            elseif app.PlayRecordedButton.Text == "STOP"
                app.PlayRecordedButton.Text = "PLAY";

                clear sound;
            end
        end

        % Button pushed function: PlayCajonButton
        function PlayCajonButtonPushed(app, event)
            if app.PlayCajonButton.Text == "PLAY"
                app.PlayCajonButton.Text = "STOP";
                app.PlayRecordedButton.Text = "PLAY";
                app.PlayKotoButton.Text = "PLAY";

                clear sound;
                sound(0.7*app.cajonAudioVec, app.Fs);
            elseif app.PlayCajonButton.Text == "STOP"
                app.PlayCajonButton.Text = "PLAY";

                clear sound;
            end
        end

        % Button pushed function: PlayKotoButton
        function PlayKotoButtonPushed(app, event)
            if app.PlayKotoButton.Text == "PLAY"
                app.PlayKotoButton.Text = "STOP";
                app.PlayRecordedButton.Text = "PLAY";
                app.PlayCajonButton.Text = "PLAY";

                clear sound;
                sound(0.7*app.kotoAudioVec, app.Fs);
            elseif app.PlayKotoButton.Text == "STOP"
                app.PlayKotoButton.Text = "PLAY";
                clear sound;
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [file, path] = uigetfile('*.wav');
            [app.ogAudioVec, app.Fs] = audioread(append(path, file));
            app.Fs = 44100;
            figure(app.FilterDesignUIFigure);
            app.showMax = "off";
            app.update();
        end

        % Menu selected function: CajonMenu
        function CajonMenuSelected(app, event)
            [file, path] = uiputfile('*.wav',"Save Cajon Audio", "cajon_output.wav");
            audiowrite(append(path, file), app.cajonAudioVec, app.Fs);
        end

        % Menu selected function: TaishogotoMenu
        function TaishogotoMenuSelected(app, event)
            [file, path] = uiputfile('*.wav', "Save Taishogoto Audio", "koto_output.wav");
            audiowrite(append(path, file), app.kotoAudioVec, app.Fs);
        end

        % Menu selected function: InputMenu
        function InputMenuSelected(app, event)
            app.dialogapp = input_dialog_box(app);
        end

        % Menu selected function: NormalizeMenu
        function ContextMenu2Opening(app, event)
            if app.normalize == "off"
                app.normalize = "on";
                app.NormalizeMenu.Checked = "on";
            elseif app.normalize == "on"
                app.normalize = "off";
                app.NormalizeMenu.Checked = "off";
            end
            app.update();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterDesignUIFigure = uifigure('Visible', 'off');
            app.FilterDesignUIFigure.Name = 'Filter Design';
            app.SaveMenu = uimenu(app.FilterDesignUIFigure);
            app.SaveMenu.Text = 'Save';
            app.CajonMenu = uimenu(app.SaveMenu);
            app.CajonMenu.MenuSelectedFcn = createCallbackFcn(app, @CajonMenuSelected, true);
            app.CajonMenu.Text = 'Cajon';
            app.TaishogotoMenu = uimenu(app.SaveMenu);
            app.TaishogotoMenu.MenuSelectedFcn = createCallbackFcn(app, @TaishogotoMenuSelected, true);
            app.TaishogotoMenu.Text = 'Taishogoto';
            app.LoadMenu = uimenu(app.FilterDesignUIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.Menu = uimenu(app.FilterDesignUIFigure);
            app.Menu.Text = 'Menu';
            app.InputMenu = uimenu(app.Menu);
            app.InputMenu.MenuSelectedFcn = createCallbackFcn(app, @InputMenuSelected, true);
            app.InputMenu.Text = 'Input';
            app.GridLayout = uigridlayout(app.FilterDesignUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AudioTab = uitab(app.TabGroup);
            app.AudioTab.Title = 'Audio';
            app.GridLayout_2 = uigridlayout(app.AudioTab);
            app.ogAudioFT = uiaxes(app.GridLayout_2);
            title(app.ogAudioFT, 'Frequency Domain')
            xlabel(app.ogAudioFT, 'Frequency (Hz)')
            ylabel(app.ogAudioFT, 'Magnitude')
            zlabel(app.ogAudioFT, 'Z')
            app.cajonAudioFT = uiaxes(app.GridLayout_2);
            title(app.cajonAudioFT, 'Frequency Domain')
            xlabel(app.cajonAudioFT, 'Frequency (Hz)')
            ylabel(app.cajonAudioFT, 'Magnitude')
            zlabel(app.cajonAudioFT, 'Z')
            app.ogAudioAxes = uiaxes(app.GridLayout_2);
            title(app.ogAudioAxes, 'Original Audio')
            xlabel(app.ogAudioAxes, 'time (s)')
            ylabel(app.ogAudioAxes, 'Amplitude')
            zlabel(app.ogAudioAxes, 'Z')
            app.cajonAxes = uiaxes(app.GridLayout_2);
            title(app.cajonAxes, 'Cajon Beatbox 50Hz - 500Hz')
            xlabel(app.cajonAxes, 'time (s)')
            ylabel(app.cajonAxes, 'Amplitude')
            zlabel(app.cajonAxes, 'Z')
            app.kotoAxes = uiaxes(app.GridLayout_2);
            title(app.kotoAxes, 'Taishogoto(Koto guitar) 800Hz - 2kHz')
            xlabel(app.kotoAxes, 'time (s)')
            ylabel(app.kotoAxes, 'Amplitude')
            zlabel(app.kotoAxes, 'Z')
            app.kotoAudioFT = uiaxes(app.GridLayout_2);
            title(app.kotoAudioFT, 'Frequency Domain')
            xlabel(app.kotoAudioFT, 'Frequency (Hz)')
            ylabel(app.kotoAudioFT, 'Magnitude')
            zlabel(app.kotoAudioFT, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout_2);
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.RecordingLabel = uilabel(app.GridLayout5);
            app.RecordingLabel.Visible = 'off';
            app.RecordingLabel.Text = 'Recording...';
            app.PlayRecordedButton = uibutton(app.GridLayout2, 'push');
            app.PlayRecordedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordedButtonPushed, true);
            app.PlayRecordedButton.Text = 'PLAY';
            app.RecordButton = uibutton(app.GridLayout2, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'REC';
            app.GridLayout2_2 = uigridlayout(app.GridLayout_2);
            app.PlayCajonButton = uibutton(app.GridLayout2_2, 'push');
            app.PlayCajonButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCajonButtonPushed, true);
            app.PlayCajonButton.Text = 'PLAY';
            app.GridLayout2_3 = uigridlayout(app.GridLayout_2);
            app.PlayKotoButton = uibutton(app.GridLayout2_3, 'push');
            app.PlayKotoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayKotoButtonPushed, true);
            app.PlayKotoButton.Text = 'PLAY';
            app.FrequencyResponseTab = uitab(app.TabGroup);
            app.FrequencyResponseTab.Title = 'Frequency Response';
            app.GridLayout6 = uigridlayout(app.FrequencyResponseTab);
            app.freqResponseGraph = uiaxes(app.GridLayout6);
            title(app.freqResponseGraph, 'Frequency Response')
            xlabel(app.freqResponseGraph, 'Frequency (Hz)')
            ylabel(app.freqResponseGraph, 'Gain')
            zlabel(app.freqResponseGraph, 'Z')
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.FilterCoefficients8thOrderLabel = uilabel(app.GridLayout7);
            app.FilterCoefficients8thOrderLabel.Text = 'Filter Coefficients (8th Order)';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.CajonFilter50Hz500HzLabel = uilabel(app.GridLayout8);
            app.CajonFilter50Hz500HzLabel.Text = 'Cajon Filter (50Hz - 500Hz)';
            app.TaishogotoFilter800Hz2kHzLabel = uilabel(app.GridLayout8);
            app.TaishogotoFilter800Hz2kHzLabel.Text = 'Taishogoto Filter (800Hz - 2kHz)';
            app.bCoeffCajon = uilabel(app.GridLayout8);
            app.bCoeffCajon.Text = 'b_cajon';
            app.bCoeffKoto = uilabel(app.GridLayout8);
            app.bCoeffKoto.Text = 'b_taishogoto';
            app.aCoeffCajon = uilabel(app.GridLayout8);
            app.aCoeffCajon.Text = 'a_cajon';
            app.aCoeffKoto = uilabel(app.GridLayout8);
            app.aCoeffKoto.Text = 'a_taishogoto';
            app.ContextMenu = uicontextmenu(app.FilterDesignUIFigure);
            app.YaxisMenu = uimenu(app.ContextMenu);
            app.YaxisMenu.Text = 'Y-axis';
            app.dBMenu = uimenu(app.YaxisMenu);
            app.dBMenu.Text = 'dB';
            app.absMenu = uimenu(app.YaxisMenu);
            app.absMenu.Text = 'abs';
            % Assign app.ContextMenu
            app.freqResponseGraph.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.FilterDesignUIFigure);
            app.NormalizeMenu = uimenu(app.ContextMenu2);
            app.NormalizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.NormalizeMenu.Text = 'Normalize';
            % Assign app.ContextMenu2
            app.ogAudioFT.ContextMenu = app.ContextMenu2;
            app.cajonAudioFT.ContextMenu = app.ContextMenu2;
            app.kotoAudioFT.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.FilterDesignUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_layout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilterDesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterDesignUIFigure)
        end
    end
end