classdef convolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        GridLayout3      matlab.ui.container.GridLayout
        c2Label          matlab.ui.control.Label
        c1Label          matlab.ui.control.Label
        Hzto5kHzLabel    matlab.ui.control.Label
        Hzto500HzLabel   matlab.ui.control.Label
        GridLayout2      matlab.ui.container.GridLayout
        fEditField       matlab.ui.control.NumericEditField
        fEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp; % Description
        f = 0;
        t = 0;
        fs = 44100;
        bl;
        bh;
        al;
        ah;
        y = 0;
    end
    
    methods (Access = private)
        
        function [b1, a1, b2, a2] = computeConvolution(app)
            [b1, a1] = filter(app.bl, app.al, app.y);
            [b2, a2] = filter(app.bh, app.ah, app.y);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.fEditField.Value = app.mainapp.f;
            app.f = app.mainapp.f;
            app.t = 0:1/app.fs:5;
            app.y = sin(2*pi*app.f/(app.fs/2)*app.t);

            app.mainapp.bl = app.bl;
            app.mainapp.al = app.al;
            app.mainapp.bh = app.bh;
            app.mainapp.ah = app.ah;

            app.computeConvolution();
        end

        % Value changed function: fEditField
        function fEditFieldValueChanged(app, event)
            app.f = app.fEditField.Value;
            app.y = sin(2*pi*app.f/(app.fs/2)*app.t);
            app.computeConvolution();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.fEditFieldLabel = uilabel(app.GridLayout2);
            app.fEditFieldLabel.Text = 'f = ';
            app.fEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fEditField.ValueChangedFcn = createCallbackFcn(app, @fEditFieldValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Hzto500HzLabel = uilabel(app.GridLayout3);
            app.Hzto500HzLabel.Text = '50Hz to 500Hz';
            app.Hzto5kHzLabel = uilabel(app.GridLayout3);
            app.Hzto5kHzLabel.Text = '800Hz to 5kHz';
            app.c1Label = uilabel(app.GridLayout3);
            app.c1Label.Text = 'c1';
            app.c2Label = uilabel(app.GridLayout3);
            app.c2Label.Text = 'c2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolution(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end