classdef input_dialog_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        fSlider              matlab.ui.control.RangeSlider
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        AddButton            matlab.ui.control.Button
        FilterButton         matlab.ui.control.Button
        fEditField           matlab.ui.control.NumericEditField
        fEditFieldLabel      matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        TimeDomainMenu       matlab.ui.container.Menu
        FrequencyDomainMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        view = "td"; % Description
        fs = 44100;
        f = 0;
        t = 0;
        y = 0;
        xLims = [1 300];
        mainapp;
    end
    
    methods (Access = private)
        
        function update(app)
           cla(app.UIAxes);
            if app.view == "td"
                plot(app.UIAxes, app.y, "Color", 'b', 'LineStyle', '-');
            elseif app.view == "fd"
                L = length(app.y);
                f_shifted = (-L/2:L/2-1)*(app.fs/L);
                positive_indices = f_shifted >= 0;
                f_positive = f_shifted(positive_indices);

                ft = fftshift(fft(app.y));
                ft_positive = ft(positive_indices);
                plot(app.UIAxes, f_positive, abs(ft_positive)/L, "Color", 'k', 'LineStyle', '-');
                xlim(app.UIAxes, app.xLims);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
        end

        % Value changed function: fEditField
        function fEditFieldValueChanged(app, event)
            app.f = app.fEditField.Value;
            app.t = 0:1/app.fs:1;
            app.y = sin(2*pi*app.f*app.t);
            app.update();
        end

        % Menu selected function: TimeDomainMenu
        function TimeDomainMenuSelected(app, event)
            app.view = "td";
            app.UIAxes.XLabel.String = "Time (s)";
            app.UIAxes.YLabel.String = "Amplitude";
            app.fSlider.Visible = "off";
            app.fSlider.Enable = "off";
            if ~isempty(app.t)
                app.update();
            end
        end

        % Menu selected function: FrequencyDomainMenu
        function FrequencyDomainMenuSelected(app, event)
            app.view = "fd";
            app.UIAxes.XLabel.String = "Frequency (Hz)";
            app.UIAxes.YLabel.String = "Magnitude";
            app.fSlider.Visible = "on";
            app.fSlider.Enable = "on";
            if ~isempty(app.t)
                app.update();
            end
        end

        % Value changing function: fSlider
        function fSliderValueChanging(app, event)
            app.xLims = event.Value;
            if ~isempty(app.t)
                app.update();
            end
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            % Send Signal to Main App
            app.mainapp.showMax = "on"; 
            app.mainapp.ogAudioVec = app.y;
            app.mainapp.update();
            app.mainapp.f = app.f;
            app.mainapp.Fs = 44100;
            delete(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Send Signal to Main App
            app.mainapp.showMax = "on"; 
            app.mainapp.ogAudioVec = app.mainapp.ogAudioVec + app.y;
            app.mainapp.update();
            app.mainapp.f = [app.mainapp.f, app.f];
            app.mainapp.Fs = 44100;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Input')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.fEditFieldLabel = uilabel(app.GridLayout3);
            app.fEditFieldLabel.Text = 'f =';
            app.fEditField = uieditfield(app.GridLayout3, 'numeric');
            app.fEditField.ValueChangedFcn = createCallbackFcn(app, @fEditFieldValueChanged, true);
            app.FilterButton = uibutton(app.GridLayout3, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.AddButton = uibutton(app.GridLayout3, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.fSlider = uislider(app.GridLayout4, 'range');
            app.fSlider.Limits = [1 2000];
            app.fSlider.ValueChangingFcn = createCallbackFcn(app, @fSliderValueChanging, true);
            app.fSlider.Enable = 'off';
            app.fSlider.Visible = 'off';
            app.fSlider.Value = [1 300];
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.TimeDomainMenu = uimenu(app.ContextMenu);
            app.TimeDomainMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeDomainMenuSelected, true);
            app.TimeDomainMenu.Text = 'Time Domain';
            app.FrequencyDomainMenu = uimenu(app.ContextMenu);
            app.FrequencyDomainMenu.MenuSelectedFcn = createCallbackFcn(app, @FrequencyDomainMenuSelected, true);
            app.FrequencyDomainMenu.Text = 'Frequency Domain';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = input_dialog_box(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end